classdef bandwidth_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        PLOTButton             matlab.ui.control.Button
        LatencyEditField       matlab.ui.control.NumericEditField
        LatencyEditFieldLabel  matlab.ui.control.Label
        ScreensEditField       matlab.ui.control.NumericEditField
        ScreensEditFieldLabel  matlab.ui.control.Label
        fpsEditField           matlab.ui.control.NumericEditField
        fpsEditFieldLabel      matlab.ui.control.Label
        BppEditField           matlab.ui.control.NumericEditField
        BppEditFieldLabel      matlab.ui.control.Label
        VEditField             matlab.ui.control.NumericEditField
        VEditFieldLabel        matlab.ui.control.Label
        HEditField             matlab.ui.control.NumericEditField
        HEditFieldLabel        matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)

            resoloution_h = app.HEditField.Value;
            resoloution_w = app.VEditField.Value;
            bits_per_pixel = app.BppEditField.Value;
            num_of_screens = app.ScreensEditField.Value;

            %Taking user inputs for time constraints
            frame_rate = app.fpsEditField.Value;
            latency = app.LatencyEditField.Value;

            %Calculations:
            latency_rate =  1000 / latency;
            comp_ratio = 1:100;
            constant = ones(100);

            wifi_7 = 23059 * constant;
            wifi_6 = 9608 * constant;
            wifi_5 = 6933 * constant;
    
            bits_per_frame = (resoloution_w * resoloution_h* bits_per_pixel * num_of_screens) ./comp_ratio;
            peak_bandwidth = max(bits_per_frame * frame_rate, bits_per_frame * latency_rate) ./1000000;
            average_bandwidth = bits_per_frame * frame_rate/1000000;
                  hold(app.UIAxes, "on");
                  plot(app.UIAxes,peak_bandwidth);
                  plot(app.UIAxes,average_bandwidth);
                  plot(app.UIAxes,wifi_7);
                  plot(app.UIAxes,wifi_6);
                  plot(app.UIAxes,wifi_5);
                  hold(app.UIAxes, "off");
                  legend(app.UIAxes,'peak_bandwidth', 'avg_bandwidth', 'wifi_7', 'wifi_6', 'wifi_5');

            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {185, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HEditFieldLabel = uilabel(app.LeftPanel);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VEditFieldLabel = uilabel(app.LeftPanel);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BppEditFieldLabel = uilabel(app.LeftPanel);
            app.BppEditFieldLabel.Text = 'Bpp';
            app.BppEditField = uieditfield(app.LeftPanel, 'numeric');
            app.fpsEditFieldLabel = uilabel(app.LeftPanel);
            app.fpsEditFieldLabel.Text = 'fps';
            app.fpsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ScreensEditFieldLabel = uilabel(app.LeftPanel);
            app.ScreensEditFieldLabel.Text = '# Screens';
            app.ScreensEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LatencyEditFieldLabel = uilabel(app.LeftPanel);
            app.LatencyEditFieldLabel.Text = 'Latency';
            app.LatencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PLOTButton = uibutton(app.LeftPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Compression Factor VS Bandwidth')
            xlabel(app.UIAxes, 'Compression Ratio')
            ylabel(app.UIAxes, 'Bsndwidth')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bandwidth_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end